classdef commpartb < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        TbmsSpinnerLabel           matlab.ui.control.Label
        TbmsSpinner                matlab.ui.control.Spinner
        StartButton                matlab.ui.control.Button
        AVSpinnerLabel             matlab.ui.control.Label
        AVSpinner                  matlab.ui.control.Spinner
        DropDown                   matlab.ui.control.DropDown
        turnonswitchtouseexternalinputSwitchLabel  matlab.ui.control.Label
        turnonswitchtouseexternalinputSwitch  matlab.ui.control.Switch
        EditField                  matlab.ui.control.EditField
        FilepathMATfilesonlyLabel  matlab.ui.control.Label
        RightPanel                 matlab.ui.container.Panel
        Label                      matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: AVSpinner
        function AVSpinnerValueChanged(app, event)
            valueA = app.AVSpinner.Value;
            
        end

        % Value changed function: TbmsSpinner
        function TbmsSpinnerValueChanged(app, event)
            valueTb = app.TbmsSpinner.Value;
            
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            valueDD = app.DropDown.Value;
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            Am= app.AVSpinner.Value;
            Tb= app.TbmsSpinner.Value;
            DD= app.DropDown.Value;
            swval=app.turnonswitchtouseexternalinputSwitch.Value;
            if Tb~=0 && Am~=0
                if strcmpi(swval,'on')==0
                    [bits, sig, tvec1] = init(Tb);
                else
                    bstr=load(convertCharsToStrings(app.EditField.Value));
                    bcell=struct2cell(bstr);
                    bits=cell2mat(bcell);
                    NBits=length(bits);
                    tvec1=linspace(0,NBits*Tb,NBits*Tb);
                    sig=zeros(1,length(tvec1));
                end
                
                if DD=="Unipolar NRZ"
                    sig = unipNRZ(bits,Tb,sig);
                elseif DD=="Unipolar RZ"
                    sig = unipRZ(bits,Tb,sig);
                elseif DD=="Polar NRZ"
                    sig = pNRZ(bits,Tb,sig);
                elseif DD=="Polar RZ"
                    sig = pRZ(bits,Tb,sig);
                elseif DD== "AMI"
                    sig = AMI(bits,Tb,sig);
                elseif DD=="Manchester Code"
                    sig = SP(bits,Tb,sig); 
                end
                app.Label.Text="Input bits: "+int2str(bits);
                plot(app.UIAxes,tvec1,Am*sig)
                title(app.UIAxes,DD+" line code");
                xlabel(app.UIAxes,"Time (milliseconds)");
                ylabel(app.UIAxes,"Amplitude (Volts)");
                drawnow
            end
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Value changed function: 
        % turnonswitchtouseexternalinputSwitch
        function turnonswitchtouseexternalinputSwitchValueChanged(app, event)
           value=app.turnonswitchtouseexternalinputSwitch.Value;
            if strcmpi(value,'on')==1
            app.EditField.Visible=true;
            app.EditField.Editable=true;
            app.EditField.Enable=true;
            app.FilepathMATfilesonlyLabel.Visible=true;
            else
            app.EditField.Visible=false;
            app.EditField.Editable=false;
            app.EditField.Enable=false;
            app.FilepathMATfilesonlyLabel.Visible=false; 
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TbmsSpinnerLabel = uilabel(app.LeftPanel);
            app.TbmsSpinnerLabel.Text = 'Tb(ms)';
            app.TbmsSpinner = uispinner(app.LeftPanel);
            app.TbmsSpinner.ValueChangedFcn = createCallbackFcn(app, @TbmsSpinnerValueChanged, true);
            app.TbmsSpinner.Tag = 'Tb';
            app.StartButton = uibutton(app.LeftPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Tag = 'Start';
            app.StartButton.Text = 'Start';
            app.AVSpinnerLabel = uilabel(app.LeftPanel);
            app.AVSpinnerLabel.Text = 'A(V) ';
            app.AVSpinner = uispinner(app.LeftPanel);
            app.AVSpinner.ValueChangedFcn = createCallbackFcn(app, @AVSpinnerValueChanged, true);
            app.AVSpinner.Tag = 'Amp';
            app.DropDown = uidropdown(app.LeftPanel);
            app.DropDown.Items = {'Unipolar NRZ', 'Unipolar RZ', 'Polar NRZ', 'Polar RZ', 'AMI', 'Manchester Code'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Tag = 'LC';
            app.DropDown.Value = 'Unipolar NRZ';
            app.turnonswitchtouseexternalinputSwitchLabel = uilabel(app.LeftPanel);
            app.turnonswitchtouseexternalinputSwitchLabel.Text = 'turn on switch to use external input';
            app.turnonswitchtouseexternalinputSwitch = uiswitch(app.LeftPanel, 'slider');
            app.turnonswitchtouseexternalinputSwitch.Items = {'off', 'on'};
            app.turnonswitchtouseexternalinputSwitch.ValueChangedFcn = createCallbackFcn(app, @turnonswitchtouseexternalinputSwitchValueChanged, true);
            app.turnonswitchtouseexternalinputSwitch.Value = 'off';
            app.EditField = uieditfield(app.LeftPanel, 'text');
            app.EditField.Editable = 'off';
            app.EditField.Enable = 'off';
            app.EditField.Visible = 'off';
            app.FilepathMATfilesonlyLabel = uilabel(app.LeftPanel);
            app.FilepathMATfilesonlyLabel.Visible = 'off';
            app.FilepathMATfilesonlyLabel.Text = 'File path (MAT files only)';
            app.RightPanel = uipanel(app.GridLayout);
            app.Label = uilabel(app.RightPanel);
            app.Label.Text = '';
            app.UIAxes = uiaxes(app.RightPanel);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = commpartb

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end